classdef StepSizeSelectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        ErrorLabel              matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        Header                  matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        StepSizeEditField       matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel  matlab.ui.control.Label
        UnitDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp % Main app that called it
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainApp, stepSizeValue, stepSizeUnit, isError)
            if isError
                app.ErrorLabel.Visible = "on";
                app.StepSizeEditField.Value = stepSizeValue;
                app.UnitDropDown.Value = stepSizeUnit;
            else
                app.StepSizeEditField.Value = stepSizeValue;
                app.UnitDropDown.Value = stepSizeUnit;
            end
        end

        % Key press function: UIFigure
        function onKeypress(app, event)
            key = event.Key;
            if key == "return"
                submitPushed(app);
            end
        end

        % Button pushed function: LoadButton
        function submitPushed(app, event)
            disp("Clicked")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @onKeypress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UnitDropDown = uidropdown(app.GridLayout2);
            app.UnitDropDown.Items = {'nm', 'μm', 'mm'};
            app.UnitDropDown.Value = 'mm';
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout2);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.StepSizeEditField.Limits = [0 Inf];
            app.Header = uilabel(app.GridLayout);
            app.Header.Text = {'Enter the step size of dataset'; ''};
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @submitPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.ErrorLabel = uilabel(app.GridLayout);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error. Invalid Stepsize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StepSizeSelectorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end